classdef GiMY < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        Lamp                    matlab.ui.control.Lamp
        EditStatus              matlab.ui.control.EditField
        ProgressLabel           matlab.ui.control.Label
        Percentage5             matlab.ui.control.NumericEditField
        Percentage4             matlab.ui.control.NumericEditField
        Percentage3             matlab.ui.control.NumericEditField
        Percentage2             matlab.ui.control.NumericEditField
        Percentage1             matlab.ui.control.NumericEditField
        SongName5               matlab.ui.control.EditField
        Label_5                 matlab.ui.control.Label
        SongName4               matlab.ui.control.EditField
        Label_4                 matlab.ui.control.Label
        SongName3               matlab.ui.control.EditField
        Label_3                 matlab.ui.control.Label
        SongName2               matlab.ui.control.EditField
        Label_2                 matlab.ui.control.Label
        SongName1               matlab.ui.control.EditField
        Label                   matlab.ui.control.Label
        SongNameLabel           matlab.ui.control.Label
        PercentageofMatchLabel  matlab.ui.control.Label
        RECORDButton            matlab.ui.control.Button
        GiMYGivemetheSongLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RECORDButton
        function RECORDButtonPushed(app, event)

            % Clear the song name and percentage value boxes each time the
            % "RECORD" button is pushed to avoid confusion.
            app.Image.Visible = "off";
            app.Lamp.Color = 'g';
            app.RECORDButton.Enable = "off"; %Make the button unavailable while in process.
            app.SongName1.Value = ' ';
            app.SongName2.Value = ' ';
            app.SongName3.Value = ' ';
            app.SongName4.Value = ' ';
            app.SongName5.Value = ' ';
            app.Percentage1.Value = 0;
            app.Percentage2.Value = 0;
            app.Percentage3.Value = 0;
            app.Percentage4.Value = 0;
            app.Percentage5.Value = 0;


            %Start recording the song when the "RECORD" button is pushed.
            app.EditStatus.Value = sprintf('Recording...');
            fs = 44100; %Recording sampling frequency
            recordingTime = 10; %Record for 15 seconds

            %{
            Record the audio by using the "audiorecorder" function. Here,
            we use sampling frequency of fs = 44100Hz, 16bit to represent
            each sample and we record a mono audio since the songs in the
            database are also represented as mono audios.
            %}
            RECORDING = audiorecorder(fs,16,1); %Recording the audio
            recordblocking(RECORDING,recordingTime); %Record via microphone for 15 seconds.
            RECORDED = getaudiodata(RECORDING); %Save the audio data in "RECORDED" variable.

            %{
            Convert the data in a '.wav' file. Note that "audiowrite" function does
            not support ".mp3" format since r2015b version.
            %}
            audiowrite('mic.wav',RECORDED,fs);

            %Create constellation for the recording
            hashRecording = hashing('mic.wav');
            % Appending the track ID as 0
            hashRecording = [zeros(1,size(hashRecording,2)); hashRecording];

            %{
            We must access the song database and the names of the songs
            to compare the recording with the database and display the name
            of the matched songs in the app. Therefore, we declare these
            variables here as global variables to use them for the rest of
            the algorithm.
            %}

            global songNames
            global database

            app.Lamp.Color = 'r'; %"RECORD" button is not available while processing.
            app.EditStatus.Value = 'Processing...';
            drawnow()

            % Defining the match thresholds
            th_f1 = 3;
            th_f2 = 3;
            th_delta_t = 0.050;
            tic
            % For each recorded hash make matrix subtractions to find
            % matching hashes
            matches_diff = []; % Matching hashes
            % Search for the matching hashes
            for i = 1:size(hashRecording,2)
                % Take the difference of each hash in the recording with
                % the who data.
                difference = abs(database - hashRecording(:,i));
                % Logical index = 1 if the difference satisfies threshold
                % conditions. The indices represent the indices of the
                % matching hashes inside the difference matrix.
                indices = difference(1,:) > 0 & difference(2,:) < th_f1 & ...
                    difference(3,:) < th_f2 & difference(4,:) < th_delta_t;

                % Hold the values of time diffs and track ids
                matches_diff = [matches_diff, difference([1,5],indices)];
            end

            % Among the matching hashes we will look into the absolute time
            % differences, we expect to observe a constant absolute time difference
            % between the mathing hashes if the song is detected correctly.

            % We will hold the maximum bin. We expect a bin to be highly prominent.
            max_bins = zeros(1,length(songNames));
            for i = 1:length(songNames)
                % Select the matches from matches_diff from each track ID.
                time_diff_track_i = matches_diff(2,matches_diff(1,:) == i);
                % Group the time diffs into bins. Each time diff is shifted by a little
                % amount so that we wont have values at the edges of the bins.
                [bin_counts, edges] = histcounts(time_diff_track_i,'BinWidth',0.040);
                max_bins(i) = sum(maxk(bin_counts,2));
            end

            % Among the max_bins choose the most prominent bins.
            [max_max_bins, track_ids] = maxk(max_bins,5);
            toc
            %Find location (index) of the five songs with most matches and
            %their corresponding matches.
           
            % Calculate the probabilites
            percentages = max_max_bins/sum(max_max_bins)*100;
            %We will calculate the percentage of matched hashes for these
            %five most similar songs.
            %percentages = zeros(1,length(max5));
            
            %for i = 1:length(max5)
            %{
            Similarity percentage to each of these songs is defined as the
            number of matched hashes of the recording and that song devided
            by the total number of hashes of the recording, multiplied by
            100.
            %}

            %percentages(i) = (max5(i)/size(hashRecording,2))*100;
            %end
            %{
            If none of the songs in the database has more than 1 percent 
            similarity to the recording, than we conclude that the 
r           recording does not correspond to any song of the database.
            %}
            if max(percentages) < 30
                app.EditStatus.Value = sprintf('The song does not have a match in the database.');
                app.Image.Visible = "off";

                %{
            If at least one song in the database has more than 1 percent
            similarity to the recording, than we conclude that the
            recording is indeed similar enough to some songs in the
            database. In that case, we display the names of the most 5
            similar songs and their corresponding similarity percentages.
                %}
            else
                app.SongName1.Value = (songNames{track_ids(1)}(1:end-4));
                app.SongName2.Value = (songNames{track_ids(2)}(1:end-4));
                app.SongName3.Value = (songNames{track_ids(3)}(1:end-4));
                app.SongName4.Value = (songNames{track_ids(4)}(1:end-4));
                app.SongName5.Value = (songNames{track_ids(5)}(1:end-4));
                app.Percentage1.Value = percentages(1);
                app.Percentage2.Value = percentages(2);
                app.Percentage3.Value = percentages(3);
                app.Percentage4.Value = percentages(4);
                app.Percentage5.Value = percentages(5);
                app.EditStatus.Value = sprintf('The song is successfully identified.');
                app.Image.Visible = "on";
            end

            app.Lamp.Color = 'w'; %" RECORD" button returns to standby mode.
            app.RECORDButton.Enable = "on"; %Make the button available again.
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GiMYGivemetheSongLabel = uilabel(app.UIFigure);
            app.GiMYGivemetheSongLabel.Text = 'GiMY - Give me the Song';
            app.RECORDButton = uibutton(app.UIFigure, 'push');
            app.RECORDButton.ButtonPushedFcn = createCallbackFcn(app, @RECORDButtonPushed, true);
            app.RECORDButton.Text = 'RECORD';
            app.PercentageofMatchLabel = uilabel(app.UIFigure);
            app.PercentageofMatchLabel.Text = 'Percentage of Match';
            app.SongNameLabel = uilabel(app.UIFigure);
            app.SongNameLabel.Text = 'Song Name';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1.';
            app.SongName1 = uieditfield(app.UIFigure, 'text');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '2.';
            app.SongName2 = uieditfield(app.UIFigure, 'text');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '3.';
            app.SongName3 = uieditfield(app.UIFigure, 'text');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '4.';
            app.SongName4 = uieditfield(app.UIFigure, 'text');
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '5.';
            app.SongName5 = uieditfield(app.UIFigure, 'text');
            app.Percentage1 = uieditfield(app.UIFigure, 'numeric');
            app.Percentage2 = uieditfield(app.UIFigure, 'numeric');
            app.Percentage3 = uieditfield(app.UIFigure, 'numeric');
            app.Percentage4 = uieditfield(app.UIFigure, 'numeric');
            app.Percentage5 = uieditfield(app.UIFigure, 'numeric');
            app.ProgressLabel = uilabel(app.UIFigure);
            app.ProgressLabel.Text = 'Progress:';
            app.EditStatus = uieditfield(app.UIFigure, 'text');
            app.EditStatus.Value = 'Waiting for a song';
            app.Lamp = uilamp(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Hand-drawn-gold-crown-Clipart-PNG.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GiMY

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end